classdef GestoreAnalisiPN1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GestoreAnalisiPN1UIFigure       matlab.ui.Figure
        GrigliaEsterna                  matlab.ui.container.GridLayout
        Pagine                          matlab.ui.container.TabGroup
        PaginaPN                        matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        GridRIDOTTA                     matlab.ui.container.GridLayout
        TableH_R                        matlab.ui.control.Table
        TablePOST_R                     matlab.ui.control.Table
        TablePRE_R                      matlab.ui.control.Table
        MATRICEHLabel_R                 matlab.ui.control.Label
        MATRICEPOSTLabel_R              matlab.ui.control.Label
        MATRICEPRELabel_R               matlab.ui.control.Label
        MATRICECLabel_R                 matlab.ui.control.Label
        TableC_R                        matlab.ui.control.Table
        TablePosti_R                    matlab.ui.control.Table
        TableTransizioni_R              matlab.ui.control.Table
        GridCOMPLETA                    matlab.ui.container.GridLayout
        TableH_C                        matlab.ui.control.Table
        TablePOST_C                     matlab.ui.control.Table
        TablePRE_C                      matlab.ui.control.Table
        MATRICEHLabel                   matlab.ui.control.Label
        MATRICEPOSTLabel_C              matlab.ui.control.Label
        MATRICEPRELabel_C               matlab.ui.control.Label
        MATRICECLabel_C                 matlab.ui.control.Label
        TableC_C                        matlab.ui.control.Table
        TablePosti_C                    matlab.ui.control.Table
        TableTransizioni_C              matlab.ui.control.Table
        PNRIDOTTALabel                  matlab.ui.control.Label
        PNCOMPLETALabel                 matlab.ui.control.Label
        PaginaMacchinari                matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        CALCOLAPNButton                 matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        MACCHINARIOLabel                matlab.ui.control.Label
        DAANALIZZARELabel               matlab.ui.control.Label
        GrigliaMacchinari               matlab.ui.container.GridLayout
        PaginaTransizioni               matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        CALCOLAPNButton_2               matlab.ui.control.Button
        GridLayout7                     matlab.ui.container.GridLayout
        SERVERLabel                     matlab.ui.control.Label
        MACCHINARIOLabel_2              matlab.ui.control.Label
        RATELabel                       matlab.ui.control.Label
        PESOLabel                       matlab.ui.control.Label
        TIPOLabel                       matlab.ui.control.Label
        TRANSIZIONELabel                matlab.ui.control.Label
        GrigliaTransizioni              matlab.ui.container.GridLayout
        PaginaPosti                     matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        CALCOLAPNButton_3               matlab.ui.control.Button
        GridLayout9                     matlab.ui.container.GridLayout
        M0Label                         matlab.ui.control.Label
        MACCHINARIOLabel_3              matlab.ui.control.Label
        POSTOLabel                      matlab.ui.control.Label
        GrigliaPosti                    matlab.ui.container.GridLayout
        Indici_prestazione              matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        Tabella_EFF                     matlab.ui.control.Table
        UsareperthroughputsistemaLabel  matlab.ui.control.Label
        TPU_sistema                     matlab.ui.control.DropDown
        Tabella_WIP                     matlab.ui.control.Table
        CercaBottone                    matlab.ui.control.Button
        CercaParola                     matlab.ui.control.EditField
        StatoPN                         matlab.ui.control.Image
        EsportaButton                   matlab.ui.control.Button
        VisualizzagrafoButton           matlab.ui.control.Button
        CalcolagrafoButton              matlab.ui.control.Button
        ImportaDatiButton               matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        EsportaPNMenu                   matlab.ui.container.Menu
        EsportaGrafoMenu                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        PN
        % |- Macchinari (table con posti transizioni e analizzare)
        % |- Completa
        % |     |- T table (Transizione | Masxchera | Peso | Rate | Server)
        % |     |- P
        % |     |- M0
        % |     |- C
        % |     |- Pre
        % |     |- Post
        % |     |- H
        % |- Ridotta
        % |     |- T table (Transizione | Masxchera | Peso | Rate | Server)
        % |     |- P
        % |     |- M0
        % |     |- C
        % |     |- Pre
        % |     |- Post
        % |     |- H
        Grafo

        Macchinari
        PN_Calcolata = false
        img_aggiornata
        img_daAggiornare

        % Tab macchinari
        Aggiungi                    matlab.ui.control.Button
        NomeMacchinario             matlab.ui.control.EditField
        DaAnalizzare                matlab.ui.container.ButtonGroup
        AnalizzareSi                matlab.ui.control.RadioButton
        AnalizzareNo                matlab.ui.control.RadioButton
        Rimuovi                     matlab.ui.control.Button

        % Tab transizioni
        Transizioni_nomi            matlab.ui.control.EditField
        Scelta_tipo                 matlab.ui.container.ButtonGroup
        Temporizzata                matlab.ui.control.RadioButton
        Immediata                   matlab.ui.control.RadioButton
        Transizioni_Peso            matlab.ui.control.NumericEditField
        Transizioni_Rate            matlab.ui.control.NumericEditField
        Transizioni_Macc            matlab.ui.control.DropDown
        
        Scelta_server               matlab.ui.container.ButtonGroup
        single_server               matlab.ui.control.RadioButton
        multiple_server             matlab.ui.control.RadioButton
        infinite_server             matlab.ui.control.RadioButton
        transizioni_n_server        matlab.ui.control.NumericEditField

        colore_Immediata    = [1.0 1.0 0.3];
        colore_Temporizzata = [0.5 0.5 1.0];
        colore_disabilitata = [0.9 0.9 0.9];
        colore_abilitata    = [1.0 1.0 1.0];
        colore_AnalizzareSi = [0.3 1.0 0.3];
        colore_AnalizzareNo = [1.0 0.7 0.3];
        colore_single= [1.0 0.4 0.2];
        colore_multi= [1.0 0.1 0.5];
        colore_inf= [1.0 0.2 0.6];

        % Tab posti
        Posti_nomi                  matlab.ui.control.EditField
        Posti_Macc                  matlab.ui.control.DropDown
        Posti_M0                    matlab.ui.control.NumericEditField

        Salva                       matlab.ui.control.Button
    end
    
    methods (Access = private)
        function PN_ = ImportaDatiDaExcel(app,file,sheet)
            % Leggo la prima colonna del foglio excel indicato
            T=readtable(file,'sheet',sheet,'DataRange','A:A');

            % Individuo le rige che separano le diverse matrici
            riga1 = find(string(T.PetriNetIncidenceAndMarking)=='Forwards incidence matrix I+');
            riga2 = find(string(T.PetriNetIncidenceAndMarking)=='Backwards incidence matrix I-');
            riga3 = find(string(T.PetriNetIncidenceAndMarking)=='Combined incidence matrix I');
            riga4 = find(string(T.PetriNetIncidenceAndMarking)=='Inhibition matrix H');
            riga5 = find(string(T.PetriNetIncidenceAndMarking)=='Marking');
            riga6 = find(string(T.PetriNetIncidenceAndMarking)=='Enabled transitions');

            % Importo le tabelle delle singole matrici
            Post        = readtable(file,'sheet',sheet,'DataRange',[num2str(riga1+1),':',num2str(riga2-1)]);
            Post        = SistemaTabella(app,Post);
            PN_.Post     = table2array(Post(:,2:end));

            Pre         = readtable(file,'sheet',sheet,'DataRange',[num2str(riga2+1),':',num2str(riga3-1)]);
            Pre         = SistemaTabella(app,Pre);
            PN_.Pre      = table2array(Pre(:,2:end));

            Combinazione= readtable(file,'sheet',sheet,'DataRange',[num2str(riga3+1),':',num2str(riga4-1)]);
            Combinazione= SistemaTabella(app,Combinazione);
            PN_.C        = table2array(Combinazione(:,2:end));

            Inibizione  = readtable(file,'sheet',sheet,'DataRange',[num2str(riga4+1),':',num2str(riga5-1)]);
            Inibizione  = SistemaTabella(app,Inibizione);
            PN_.H        = table2array(Inibizione(:,2:end));

            M0          = readtable(file,'sheet',sheet,'DataRange',[num2str(riga5+1),':',num2str(riga6-1)]);
            M0          = SistemaTabella(app,M0);
            PN_.M0       = table2array(M0(1,2:end))';

            % Elimino i messaggi di warning:
            clc;

            % Salvo l'ordine dei posti e delle transizioni
            nomiT=string(Post(1,2:end).Properties.VariableNames)';
            maschera=ones(size(nomiT));
            peso=zeros(size(nomiT));
            rate=zeros(size(nomiT));
            server=ones(size(nomiT));
            PN_.T = table(nomiT,maschera,peso,rate,server,'VariableNames',["Transizione" "Maschera" "Peso" "Rate" "Server"]);
            PN_.P = string(Post.Var1);

            % Verifico che l'ordine dei posti e delle transizioni sia lo stesso per
            % tutte le matrici
            TPost         = string(Post.Properties.VariableNames);
            TPre          = string(Pre.Properties.VariableNames);
            TCombinazione = string(Combinazione.Properties.VariableNames);
            TInibizione   = string(Inibizione.Properties.VariableNames);
            if ~all(TPost==TPre & TPost==TCombinazione & TPost==TInibizione)
                uialert(app.GestoreAnalisiPN1UIFigure, "Attenzione, le transizioni non hanno lo stesso ordine.","File manomesso", "Icon","error");
            end
            PPost         = standardizza(app,string(Post.Var1));
            PPre          = standardizza(app,string(Pre.Var1));
            PCombinazione = standardizza(app,string(Combinazione.Var1));
            PInibizione   = standardizza(app,string(Inibizione.Var1));
            PM0           = standardizza(app,string(M0(:,2:end).Properties.VariableNames))';
            if ~all(PPost==PPre & PPost==PCombinazione & PPost==PInibizione & PPost==PM0)
                uialert(app.GestoreAnalisiPN1UIFigure, "Attenzione, i posti non hanno lo stesso ordine.","File manomesso", "Icon","error");
            end
        end
        
        function tabella1 = SistemaTabella(~,tabella)
            tabella1 = tabella(2:end,:);
            tabella1=rmmissing(tabella1,2);
        end
        
        function stringa1 = standardizza(~,stringa)
            stringa1 = upper(stringa);
            stringa1 = strrep(stringa1," ","");
            stringa1 = strrep(stringa1,"À","_");
        end
        
        function s=array2string(~,array)
            s='[';
            for i=1:length(array)-1
                s=[s,' ',num2str(array(i)),','];
            end
            s=[s,' ',num2str(array(end)), ']'];
        end

        function Resetta(app)
            delete(app.Aggiungi);
            
            %for i=1:length(app.NomeMacchinario)
                delete(app.NomeMacchinario);
                delete(app.DaAnalizzare);
                delete(app.Rimuovi);
            %end
            app.GrigliaMacchinari.RowHeight={};

            %for i=1:length(app.Transizioni_nomi)
                delete(app.Transizioni_nomi);
                delete(app.Scelta_tipo);
                delete(app.Transizioni_Peso);
                delete(app.Transizioni_Rate);
                delete(app.Scelta_server);
                delete(app.transizioni_n_server);
                delete(app.Transizioni_Macc);
            %end
            app.GrigliaTransizioni.RowHeight={};

            %for i=1:length(app.Posti_nomi)
                delete(app.Posti_nomi);
                delete(app.Posti_M0);
                delete(app.Posti_Macc);
            %end
            app.GrigliaPosti.RowHeight = {};

            app.StatoPN.Visible="off";
        end
        
        function Inizializza(app)

            app.StatoPN.ImageSource=app.img_aggiornata;
            app.StatoPN.Visible="on";

%% MACCHINARI =============================================================
            for i=1:length(app.Macchinari)
            
                app.GrigliaMacchinari.RowHeight(i)={50};

                % Create NomeMacchinario
                app.NomeMacchinario(i) = uieditfield(app.GrigliaMacchinari, 'text');
                app.NomeMacchinario(i).Layout.Row = i;
                app.NomeMacchinario(i).Layout.Column = 2;
                app.NomeMacchinario(i).Value = app.Macchinari(i);
                app.NomeMacchinario(i).Tag = num2str(i);
                app.NomeMacchinario(i).CharacterLimits = [1 Inf];
                app.NomeMacchinario(i).ValueChangedFcn = createCallbackFcn(app, @NomeMacchinarioValueChanged, true);

                % Create DaAnalizzare
                app.DaAnalizzare(i) = uibuttongroup(app.GrigliaMacchinari);
                app.DaAnalizzare(i).BackgroundColor = [1 1 1];
                app.DaAnalizzare(i).Layout.Row = i;
                app.DaAnalizzare(i).Layout.Column = 3;
                app.DaAnalizzare(i).SelectionChangedFcn = createCallbackFcn(app, @DaAnalizzareSelectionChanged, true);
                app.DaAnalizzare(i).Tag = num2str(i);

                % Create AnalizzareSi
                app.AnalizzareSi = uiradiobutton(app.DaAnalizzare(i));
                app.AnalizzareSi.Text = 'Si';
                app.AnalizzareSi.Position = [10 25 100 22];
            
                % Create AnalizzareNo
                app.AnalizzareNo = uiradiobutton(app.DaAnalizzare(i));
                app.AnalizzareNo.Text = 'No';
                app.AnalizzareNo.Position = [10 2 100 22];
                
                if app.PN.Macchinari.DaAnalizzare(i)
                    app.AnalizzareSi.Value=true;
                    app.DaAnalizzare(i).BackgroundColor=app.colore_AnalizzareSi;
                else
                    app.AnalizzareSi.Value=false;
                    app.DaAnalizzare(i).BackgroundColor=app.colore_AnalizzareNo;
                end

                % Create Rimuovi
                app.Rimuovi(i) = uibutton(app.GrigliaMacchinari, 'push');
                app.Rimuovi(i).ButtonPushedFcn = createCallbackFcn(app, @RimuoviButtonPushed, true);
                app.Rimuovi(i).BackgroundColor = [0.6902 0.8392 1];
                app.Rimuovi(i).Layout.Row = i;
                app.Rimuovi(i).Layout.Column = 4;
                app.Rimuovi(i).Text = '-';
                app.Rimuovi(i).FontSize = 18;
                app.Rimuovi(i).Tag = num2str(i);
            end

            % Create Aggiungi
            app.Aggiungi = uibutton(app.GrigliaMacchinari, 'push');
            app.Aggiungi.ButtonPushedFcn = createCallbackFcn(app, @AggiungiButtonPushed, true);
            app.Aggiungi.BackgroundColor = [0.6902 0.8392 1];
            app.GrigliaMacchinari.RowHeight(length(app.NomeMacchinario)+1)={50};
            app.Aggiungi.Layout.Row = length(app.NomeMacchinario)+1;
            app.Aggiungi.Layout.Column = 1;
            app.Aggiungi.Text = '+';
            app.Aggiungi.FontSize = 18;

%% TRANSIZIONI ============================================================
            for i=1:height(app.PN.Completa.T)
                app.GrigliaTransizioni.RowHeight(i)={50};
                app.Transizioni_nomi(i) = uieditfield(app.GrigliaTransizioni, 'text');
                app.Transizioni_nomi(i).Value = app.PN.Completa.T.Transizione(i);
                app.Transizioni_nomi(i).Editable = 'off';
                app.Transizioni_nomi(i).Layout.Row = i;
                app.Transizioni_nomi(i).Layout.Column = 1;

                % Create ButtonGroup
                app.Scelta_tipo(i) = uibuttongroup(app.GrigliaTransizioni);
                app.Scelta_tipo(i).Tag = num2str(i);
                app.Scelta_tipo(i).SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            
                % Create ImmediataButton
                app.Immediata = uiradiobutton(app.Scelta_tipo(i));
                app.Immediata.Text = 'Immediata';
                app.Immediata.Position = [11 24 100 22];

                % Create TemporizzataButton
                app.Temporizzata = uiradiobutton(app.Scelta_tipo(i));
                app.Temporizzata.Text = 'Temporizzata';
                app.Temporizzata.Position = [11 2 100 22];

                app.Immediata.Value = logical(app.PN.Completa.T.Maschera(i));
                
                app.Transizioni_Peso(i) = uieditfield(app.GrigliaTransizioni, 'numeric');
                app.Transizioni_Peso(i).Layout.Row = i;
                app.Transizioni_Peso(i).Layout.Column = 3;
                app.Transizioni_Peso(i).Value=app.PN.Completa.T.Peso(i);
                app.Transizioni_Peso(i).ValueChangedFcn = createCallbackFcn(app, @PNDaAggiornare, true);

                app.Transizioni_Rate(i) = uieditfield(app.GrigliaTransizioni, 'numeric');
                app.Transizioni_Rate(i).Layout.Row = i;
                app.Transizioni_Rate(i).Layout.Column = 4;
                app.Transizioni_Rate(i).Value=app.PN.Completa.T.Rate(i);
                app.Transizioni_Rate(i).Limits=[0 inf];
                app.Transizioni_Rate(i).LowerLimitInclusive = 'off';
                app.Transizioni_Rate(i).ValueChangedFcn = createCallbackFcn(app, @PNDaAggiornare, true);
                
                % Create ButtonGroup
                app.Scelta_server(i) = uibuttongroup(app.GrigliaTransizioni);
                app.Scelta_server(i).Tag = num2str(i);
                app.Scelta_server(i).SelectionChangedFcn = createCallbackFcn(app, @ServerGroupSelectionChanged, true);
                app.Scelta_server(i).Layout.Row = i;
                app.Scelta_server(i).Layout.Column = 5;
            
                % Create Single_serverButton
                app.single_server(i) = uiradiobutton(app.Scelta_server(i));
                app.single_server(i).Text = 'Single server';
                app.single_server(i).Position = [11 34 100 16];

                % Create Multiple_serverButton
                app.multiple_server(i) = uiradiobutton(app.Scelta_server(i));
                app.multiple_server(i).Text = 'Multiple server';
                app.multiple_server(i).Position = [11 18 100 16];

                % Create Infinite_serverButton
                app.infinite_server(i) = uiradiobutton(app.Scelta_server(i));
                app.infinite_server(i).Text = 'Infinite server';
                app.infinite_server(i).Position = [11 2 100 16];

                %Create numero_server
                app.transizioni_n_server(i) = uieditfield(app.GrigliaTransizioni, 'numeric');
                app.transizioni_n_server(i).Layout.Row = i;
                app.transizioni_n_server(i).Layout.Column = 6;
                app.transizioni_n_server(i).Limits=[1 inf];
                app.transizioni_n_server(i).LowerLimitInclusive = 'on';
                app.transizioni_n_server(i).Tag=num2str(i);
                app.transizioni_n_server(i).ValueChangedFcn = createCallbackFcn(app, @CorreggiNumeroServer, true);
                app.transizioni_n_server(i).Value = app.PN.Completa.T.Server(i);

                if app.transizioni_n_server(i).Value==1
                    app.single_server(i).Value=1;
                elseif app.transizioni_n_server(i).Value==Inf
                    app.infinite_server(i).Value=1;
                else
                    app.multiple_server(i).Value=1;
                end

                app.Transizioni_Macc(i) = uidropdown(app.GrigliaTransizioni);
                app.Transizioni_Macc(i).ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
                app.Transizioni_Macc(i).Layout.Row = i;
                app.Transizioni_Macc(i).Layout.Column = 7;
                app.Transizioni_Macc(i).Items = ["" app.Macchinari];
                app.Transizioni_Macc(i).Tag = string(['T',num2str(i)]);
                app.Transizioni_Macc(i).BackgroundColor=app.colore_disabilitata;
                for j=1:height(app.PN.Macchinari)
                    if ~isempty(app.PN.Macchinari.Transizioni{j}) && ismember(app.PN.Completa.T.Transizione(i),app.PN.Macchinari.Transizioni{j})
                        app.Transizioni_Macc(i).Value=app.Macchinari(j);
                        if app.PN.Macchinari.DaAnalizzare(j)
                            app.Transizioni_Macc(i).BackgroundColor = app.colore_AnalizzareSi;
                        else
                            app.Transizioni_Macc(i).BackgroundColor = app.colore_AnalizzareNo;
                        end
                        break;
                    end
                end
               Gestionetipo(app, app.Immediata.Value, i); 
            end
            
%% POSTI ==================================================================
            for i=1:height(app.PN.Completa.P)
                app.GrigliaPosti.RowHeight(i) = {50};
                app.Posti_nomi(i) = uieditfield(app.GrigliaPosti, 'text');
                app.Posti_nomi(i).Value = app.PN.Completa.P(i);
                app.Posti_nomi(i).Editable = 'off';
                app.Posti_nomi(i).Layout.Row = i;
                app.Posti_nomi(i).Layout.Column = 1;

                app.Posti_M0(i) = uieditfield(app.GrigliaPosti, 'numeric');
                app.Posti_M0(i).Layout.Row = i;
                app.Posti_M0(i).Layout.Column = 2;
                app.Posti_M0(i).Value= app.PN.Completa.M0(i);
                app.Posti_M0(i).ValueDisplayFormat = '%i';
                app.Posti_M0(i).Limits=[0 inf];
                app.Posti_M0(i).LowerLimitInclusive = 'on';
                app.Posti_M0(i).ValueChangedFcn = createCallbackFcn(app, @PNDaAggiornare, true);

                app.Posti_Macc(i) = uidropdown(app.GrigliaPosti);
                app.Posti_Macc(i).ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
                app.Posti_Macc(i).Layout.Row = i;
                app.Posti_Macc(i).Layout.Column = 3;
                app.Posti_Macc(i).Items = ["" app.Macchinari];
                app.Posti_Macc(i).BackgroundColor=[1 1 1];
                app.Posti_Macc(i).Tag = string(['P',num2str(i)]);
                app.Posti_Macc(i).BackgroundColor=app.colore_disabilitata;
                for j=1:height(app.PN.Macchinari)
                    if ~isempty(app.PN.Macchinari.Posti{j}) && ismember(app.PN.Completa.P(i),app.PN.Macchinari.Posti{j})
                        app.Posti_Macc(i).Value=app.PN.Macchinari.Macchinario(j);
                        if app.PN.Macchinari.DaAnalizzare(j)
                            app.Posti_Macc(i).BackgroundColor = app.colore_AnalizzareSi;
                        else
                            app.Posti_Macc(i).BackgroundColor = app.colore_AnalizzareNo;
                        end
                        break;
                    end
                end
            end
%% HOME ===================================================================
            ScriviHome(app);

%% INDICI DI PRESTAZIONE ==================================================
            ScriviIndici(app);
        end
        
        function NomeMacchinarioValueChanged(app, event)
            id = str2double(event.Source.Tag);
            ok=true;
            for i=1:length(app.Macchinari)
                if id~=i && string(app.NomeMacchinario(id).Value)==string(app.Macchinari(i))
                    ok=false;
                    break;
                end
            end
            if ok
                lista_vecchia = app.Macchinari;
                app.Macchinari(id)=app.NomeMacchinario(id).Value;
                CorreggiMacchinariScelti(app,lista_vecchia);
                app.StatoPN.ImageSource=app.img_daAggiornare;
            else
                app.NomeMacchinario(id).Value=app.Macchinari(id);
            end
            PNDaAggiornare(app,event);
        end

        function Gestionetipo(app,immediata,id)
            if immediata
                app.Transizioni_Rate(id).Editable='off';
                app.Transizioni_Rate(id).BackgroundColor=app.colore_disabilitata;
                app.Scelta_tipo(id).BackgroundColor=app.colore_Immediata;
                app.Scelta_server(id).BackgroundColor=app.colore_disabilitata;
                app.single_server(id).Enable='off';
                app.multiple_server(id).Enable='off';
                app.infinite_server(id).Enable='off';
                app.transizioni_n_server(id).Editable='off';
                app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
            else
                app.Transizioni_Rate(id).Editable='on';
                app.Transizioni_Rate(id).BackgroundColor=app.colore_abilitata;
                app.Scelta_tipo(id).BackgroundColor=app.colore_Temporizzata;
                app.Scelta_server(id).BackgroundColor=app.colore_abilitata;
                app.single_server(id).Enable='on';
                app.multiple_server(id).Enable='on';
                app.infinite_server(id).Enable='on';
                app.transizioni_n_server(id).Editable="off";
                app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
                if app.multiple_server(id).Value==1
                    app.transizioni_n_server(id).Editable='on';
                    app.transizioni_n_server(id).BackgroundColor=app.colore_abilitata;
                end
            end
        end

        function AggiungiButtonPushed(app, event)
            n = length(app.NomeMacchinario)+1;
            app.GrigliaMacchinari.RowHeight(n)={50};

            k=n;
            ok=true;
            while ok
                ok=false;
                nuovonome=['Macchinario',num2str(k)];
                for i=1:n-1
                    if string(nuovonome)==string(app.NomeMacchinario(i).Value)
                        ok=true;
                        break;
                    end
                end
                k=k+1;
            end

            % Create NomeMacchinario
            app.NomeMacchinario(n) = uieditfield(app.GrigliaMacchinari, 'text');
            app.NomeMacchinario(n).Layout.Row = n;
            app.NomeMacchinario(n).Layout.Column = 2;
            app.NomeMacchinario(n).Value = nuovonome;
            app.NomeMacchinario(n).Tag = num2str(n);
            app.NomeMacchinario(n).CharacterLimits = [1 Inf];
            app.NomeMacchinario(n).ValueChangedFcn = createCallbackFcn(app, @NomeMacchinarioValueChanged, true);

            % Create DaAnalizzare
            app.DaAnalizzare(n) = uibuttongroup(app.GrigliaMacchinari);
            app.DaAnalizzare(n).BackgroundColor = [1 1 1];
            app.DaAnalizzare(n).Layout.Row = n;
            app.DaAnalizzare(n).Layout.Column = 3;
            app.DaAnalizzare(n).SelectionChangedFcn = createCallbackFcn(app, @DaAnalizzareSelectionChanged, true);
            app.DaAnalizzare(n).BackgroundColor=app.colore_AnalizzareSi;
            app.DaAnalizzare(n).Tag=num2str(n);
            
            % Create AnalizzareSi
            app.AnalizzareSi = uiradiobutton(app.DaAnalizzare(n));
            app.AnalizzareSi.Text = 'Si';
            app.AnalizzareSi.Position = [10 25 100 22];
            app.AnalizzareSi.Value = true;

            % Create AnalizzareNo
            app.AnalizzareNo = uiradiobutton(app.DaAnalizzare(n));
            app.AnalizzareNo.Text = 'No';
            app.AnalizzareNo.Position = [10 2 100 22];

            app.Rimuovi(n) = uibutton(app.GrigliaMacchinari, 'push');
            app.Rimuovi(n).ButtonPushedFcn = createCallbackFcn(app, @RimuoviButtonPushed, true);
            app.Rimuovi(n).BackgroundColor = [0.6902 0.8392 1];
            app.Rimuovi(n).Layout.Row = n;
            app.Rimuovi(n).Layout.Column = 4;
            app.Rimuovi(n).Text = '-';
            app.Rimuovi(n).FontSize = 18;
            app.Rimuovi(n).Tag = num2str(n);

            app.GrigliaMacchinari.RowHeight(n+1)={50};
            app.Aggiungi.Layout.Row=n+1;
            
            app.Macchinari=[app.Macchinari string(nuovonome)];
            CorreggiMacchinariScelti(app,app.Macchinari);
            PNDaAggiornare(app,event);
        end

        function RimuoviButtonPushed(app, event)
            id = str2double(event.Source.Tag);
            
            delete(app.NomeMacchinario(id));
            delete(app.DaAnalizzare(id));
            delete(app.Rimuovi(id));

            app.Macchinari=[app.Macchinari(1:id-1),app.Macchinari(id+1:end)];
            app.NomeMacchinario=[app.NomeMacchinario(1:id-1),app.NomeMacchinario(id+1:end)];
            app.DaAnalizzare=[app.DaAnalizzare(1:id-1),app.DaAnalizzare(id+1:end)];
            app.Rimuovi=[app.Rimuovi(1:id-1),app.Rimuovi(id+1:end)];
            
            for i=id:length(app.NomeMacchinario)
                app.NomeMacchinario(i).Layout.Row=i;
                app.NomeMacchinario(i).Tag=num2str(i);
                app.DaAnalizzare(i).Layout.Row=i;
                app.DaAnalizzare(i).Tag=num2str(i);
                app.Rimuovi(i).Layout.Row=i;
                app.Rimuovi(i).Tag=num2str(i);
            end
            app.Aggiungi.Layout.Row=length(app.NomeMacchinario)+1;
            app.GrigliaMacchinari.RowHeight=ones(1,length(app.NomeMacchinario)+1)*50;
            
            CorreggiMacchinariScelti(app,app.Macchinari);
            PNDaAggiornare(app,event);
        end

        function CorreggiMacchinariScelti(app,lista_vecchia)
            for i=1:length(app.Transizioni_Macc)
                prima=find(["",lista_vecchia]==string(app.Transizioni_Macc(i).Value));
                app.Transizioni_Macc(i).Items=["" app.Macchinari];
                if prima>1
                    app.Transizioni_Macc(i).Value=app.Macchinari(prima-1);
                else
                    app.Transizioni_Macc(i).BackgroundColor=app.colore_disabilitata;
                end
            end
            for i=1:length(app.Posti_Macc)
                prima=find(["",lista_vecchia]==string(app.Posti_Macc(i).Value));
                app.Posti_Macc(i).Items=["" app.Macchinari];
                if prima>1
                    app.Posti_Macc(i).Value=app.Macchinari(prima-1);
                else
                    app.Posti_Macc(i).BackgroundColor=app.colore_disabilitata;
                end
            end
            app.PN_Calcolata=false;
        end

        function DropDownValueChanged(app, event)
            caratteri=char(event.Source.Tag);
            id=str2double(caratteri(2:end));
            if caratteri(1)=='T'
                value = app.Transizioni_Macc(id).Value;
                if value~=""
                    id_macc=find(value==app.Macchinari);
                    if app.DaAnalizzare(id_macc).SelectedObject.Text=="Si"
                        app.Transizioni_Macc(id).BackgroundColor = app.colore_AnalizzareSi;
                    else
                        app.Transizioni_Macc(id).BackgroundColor = app.colore_AnalizzareNo;
                    end
                else
                    app.Transizioni_Macc(id).BackgroundColor=app.colore_disabilitata;
                end
            elseif event.Source.Tag(1)=='P'
                value = app.Posti_Macc(id).Value;
                if value~=""
                    id_macc=find(value==app.Macchinari);
                    if app.DaAnalizzare(id_macc).SelectedObject.Text=="Si"
                        app.Posti_Macc(id).BackgroundColor = app.colore_AnalizzareSi;
                    else
                        app.Posti_Macc(id).BackgroundColor = app.colore_AnalizzareNo;
                    end
                else
                    app.Posti_Macc(id).BackgroundColor=app.colore_disabilitata;
                end
            end
            PNDaAggiornare(app,event);
        end

        function DaAnalizzareSelectionChanged(app, event)
            id=str2double(event.Source.Tag);
            if app.DaAnalizzare(id).SelectedObject.Text=="Si"
                colore = app.colore_AnalizzareSi;
            else
                colore = app.colore_AnalizzareNo;
            end
            app.DaAnalizzare(id).BackgroundColor=colore;
            for i=1:length(app.PN.Completa.T.Transizione)
                if app.Transizioni_Macc(i).Value==app.Macchinari(id)
                    app.Transizioni_Macc(i).BackgroundColor=colore;
                end
            end
            for i=1:length(app.PN.Completa.P)
                if app.Posti_Macc(i).Value==app.Macchinari(id)
                    app.Posti_Macc(i).BackgroundColor=colore;
                end
            end
            app.PN_Calcolata=false;
            PNDaAggiornare(app,event);
        end

        function ServerGroupSelectionChanged(app, event)
            id = str2double(event.Source.Tag);
            selectedButton = app.Scelta_server(id).SelectedObject.Text;
            if selectedButton~="Multiple server"
                app.transizioni_n_server(id).Editable='off';
                app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
            else
                app.transizioni_n_server(id).Editable='on';
                app.transizioni_n_server(id).BackgroundColor=app.colore_abilitata;
            end
            if selectedButton=="Single server"
                app.transizioni_n_server(id).Value=1;
            elseif selectedButton=="Infinite server"
                app.transizioni_n_server(id).Value=Inf;
            else
                app.transizioni_n_server(id).Value=2;
            end
            PNDaAggiornare(app,event);
        end
    
        function ButtonGroupSelectionChanged(app, event)
            id = str2double(event.Source.Tag);
            selectedButton = app.Scelta_tipo(id).SelectedObject.Text;
            Gestionetipo(app, selectedButton=="Immediata", id);
            PNDaAggiornare(app,event);
        end
    
        function CorreggiNumeroServer(app,event)
            id = str2double(event.Source.Tag);
            n_server = app.transizioni_n_server(id).Value;
            if n_server==1
               % Allora deve essere single server 
               app.single_server(id).Value=1;
               app.transizioni_n_server(id).Editable='off';
               app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
            elseif n_server==Inf
               % Allora deve essere infinite server 
               app.infinite_server(id).Value=Inf;
               app.transizioni_n_server(id).Editable='off';
               app.transizioni_n_server(id).BackgroundColor=app.colore_disabilitata;
            end
            PNDaAggiornare(app,event);
        end

        function ScriviHome(app)
            %% COMPLETA
            app.TableTransizioni_C.Data=app.PN.Completa.T.Transizione;

            app.TablePosti_C.Data=table(app.PN.Completa.P,app.PN.Completa.M0);

            app.TableC_C.Data=array2table(app.PN.Completa.C);
            app.TableC_C.ColumnName=app.PN.Completa.T.Transizione;
            app.TableC_C.RowName=app.PN.Completa.P;

            app.TablePRE_C.Data=array2table(app.PN.Completa.Pre);
            app.TablePRE_C.ColumnName=app.PN.Completa.T.Transizione;
            app.TablePRE_C.RowName=app.PN.Completa.P;

            app.TablePOST_C.Data=array2table(app.PN.Completa.Post);
            app.TablePOST_C.ColumnName=app.PN.Completa.T.Transizione;
            app.TablePOST_C.RowName=app.PN.Completa.P;
                        
            app.TableH_C.Data=array2table(app.PN.Completa.H);
            app.TableH_C.ColumnName=app.PN.Completa.T.Transizione;
            app.TableH_C.RowName=app.PN.Completa.P;
            
            %% RIDOTTA
            app.TableTransizioni_R.Data=app.PN.Ridotta.T.Transizione;

            app.TablePosti_R.Data=table(app.PN.Ridotta.P,app.PN.Ridotta.M0);

            app.TableC_R.Data=array2table(app.PN.Ridotta.C);
            app.TableC_R.ColumnName=app.PN.Ridotta.T.Transizione;
            app.TableC_R.RowName=app.PN.Ridotta.P;

            app.TablePRE_R.Data=array2table(app.PN.Ridotta.Pre);
            app.TablePRE_R.ColumnName=app.PN.Ridotta.T.Transizione;
            app.TablePRE_R.RowName=app.PN.Ridotta.P;

            app.TablePOST_R.Data=array2table(app.PN.Ridotta.Post);
            app.TablePOST_R.ColumnName=app.PN.Ridotta.T.Transizione;
            app.TablePOST_R.RowName=app.PN.Ridotta.P;
                        
            app.TableH_R.Data=array2table(app.PN.Ridotta.H);
            app.TableH_R.ColumnName=app.PN.Ridotta.T.Transizione;
            app.TableH_R.RowName=app.PN.Ridotta.P;
        end
        
        function ScriviIndici(app)
            % Inizializzazione DropDown per il troughput di sistema
            app.TPU_sistema.Items = app.PN.Ridotta.T.Transizione;
            if isfield(app.PN,'ImpostazioniIndici') && isfield(app.PN.ImpostazioniIndici,'T_Per_TPU') && ~isempty(app.PN.ImpostazioniIndici.T_Per_TPU) && ismember(string(app.PN.ImpostazioniIndici.T_Per_TPU),app.TPU_sistema.Items)
                app.TPU_sistema.Value=string(app.PN.ImpostazioniIndici.T_Per_TPU);
            end
            app.PN.ImpostazioniIndici.T_Per_TPU = app.TPU_sistema.Value;

            % Inizializzazione Tabella WIP
            app.Tabella_WIP.Data=table(app.PN.Ridotta.P, true(size(app.PN.Ridotta.P)),'VariableNames',["Posto" "DaConsiderare"]);
            if isfield(app.PN,'ImpostazioniIndici') && isfield(app.PN.ImpostazioniIndici,'Tabella_WIP') && ~isempty(app.PN.ImpostazioniIndici.Tabella_WIP)
                for i=1:height(app.Tabella_WIP.Data)
                    id=find(app.PN.ImpostazioniIndici.Tabella_WIP.Posto==app.Tabella_WIP.Data.Posto(i), 1);
                    if ~isempty(id)
                        app.Tabella_WIP.Data.DaConsiderare(i)=app.PN.ImpostazioniIndici.Tabella_WIP.DaConsiderare(id);
                    end
                end
            end
            app.PN.ImpostazioniIndici.Tabella_WIP=app.Tabella_WIP.Data;

            % Inizializzazione Tabella Efficienza
            if height(app.PN.Macchinari)>0
                MacchinariDaAnalizzare=app.PN.Macchinari(app.PN.Macchinari.DaAnalizzare==1,:);
                for i=1:height(MacchinariDaAnalizzare)
                    if isempty(MacchinariDaAnalizzare.Transizioni{i})
                        Transizione(i,1)={categorical(".")};
                    else
                        Transizione(i,1)={categorical(MacchinariDaAnalizzare.Transizioni{i})};
                    end
                end
                Macchinario=MacchinariDaAnalizzare.Macchinario;
                app.Tabella_EFF.Data=table(Macchinario, Transizione);
                for i=1:height(MacchinariDaAnalizzare)
                    if isfield(app.PN,'ImpostazioniIndici') && isfield(app.PN.ImpostazioniIndici,'Tabella_EFF') && ~isempty(app.PN.ImpostazioniIndici.Tabella_EFF)
                        id1=find(app.PN.ImpostazioniIndici.Tabella_EFF.Macchinario==app.Tabella_EFF.Data.Macchinario(i), 1);
                        if ~isempty(id1)
                            id2=find(app.PN.ImpostazioniIndici.Tabella_EFF.Transizione{id1}==app.Tabella_EFF.Data.Transizione{i}, 1);
                            app.Tabella_EFF.Data.Transizione{i}=app.Tabella_EFF.Data.Transizione{i}(id2);
                        else
                            app.Tabella_EFF.Data.Transizione{i}=app.Tabella_EFF.Data.Transizione{i}(1);
                        end
                    else
                        app.Tabella_EFF.Data.Transizione{i}=app.Tabella_EFF.Data.Transizione{i}(1);
                    end
                end
                tab_eff=table();
                tab_eff.Macchinario=app.Tabella_EFF.Data.Macchinario;
                tab_eff.Transizione=string(app.Tabella_EFF.Data.Transizione);
                tab_eff=tab_eff(tab_eff.Transizione ~= ".",:);
                app.PN.ImpostazioniIndici.Tabella_EFF=tab_eff;
            end
        end

        function PNDaAggiornare(app,~)
            app.PN_Calcolata=false;
            app.StatoPN.ImageSource=app.img_daAggiornare;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.img_aggiornata = fullfile(pathToMLAPP, 'Icone\Aggiornata.png');
            app.img_daAggiornare = fullfile(pathToMLAPP, 'Icone\DaAggiornare.png');
        end

        % Button pushed function: ImportaDatiButton
        function ImportaDatiMenuSelected(app, event)
            app.ImportaDatiButton.Enable=false;
            [nome,path]=uigetfile('*.mat;*.xlsx');
            scaricato=false;
            if ischar(nome) && contains(nome,".mat")
                load([path,nome]);
                if exist("PN","var") && isstruct(PN)
                    app.PN=PN;
                    app.Macchinari=app.PN.Macchinari.Macchinario';
                    scaricato=true;
                else
                    uialert(app.GestoreAnalisiPN1UIFigure, "Attenzione, il file dati matlab non rispetta il formato desiderato.","File errato", "Icon","error");
                end
            elseif ischar(nome) && contains(nome,".xlsx")
                sheets_0 = sheetnames([path,nome]);
                sheets=[];
                for i=1:length(sheets_0)
                    tableS=readtable([path,nome],'sheet',sheets_0(i),'DataRange','A1:A1');
                    title=tableS.Properties.VariableNames{1};
                    if matches(title,"PetriNetIncidenceAndMarking")
                        sheets=[sheets string(sheets_0(i))];
                    end
                end
                if isempty(sheets)
                    % NON CI SONO DATI DA USARE
                    caricare=false;
                    uialert(app.GestoreAnalisiPN1UIFigure, "Attenzione, il file excel non contiene fogli che rispettano il formato desiderato.","File errato", "Icon","error");
                elseif length(sheets)==1
                    % NON BISOGNA CHIEDERE IL FOGLIO
                    caricare=true;
                    index=1;
                else
                    % BISOGNA CHIEDERE IL FOGLIO
                    [index,caricare]=listdlg('PromptString','Seleziona il foglio','SelectionMode','single','ListSize',[90,90],'ListString',sheets);
                end
                if caricare
                    imported=ImportaDatiDaExcel(app,[path,nome],sheets(index));
                    app.PN.Completa = imported;
                    app.PN.Ridotta = imported;
                    app.PN.Macchinari=table();
                    app.Macchinari = [];
                    scaricato = true;
                end                
            end
            if scaricato
                Resetta(app);
                Inizializza(app);
            end
            app.ImportaDatiButton.Enable=true;
        end

        % Button pushed function: CALCOLAPNButton, CALCOLAPNButton_2, 
        % ...and 1 other component
        function CALCOLAPNButtonPushed(app, event)
            %% AGGIORNO MACCHINARI
            TableMacchinari=table();
            T_analizzare=[];
            P_analizzare=[];
            for i=1:length(app.Macchinari)
                temp_t=[];
                for j=1:length(app.PN.Completa.T.Transizione)
                    if app.Transizioni_Macc(j).Value==app.Macchinari(i)
                        temp_t=[temp_t string(app.PN.Completa.T.Transizione(j))];
                        if app.DaAnalizzare(i).SelectedObject.Text=="Si"
                            T_analizzare = [T_analizzare j];
                        end
                    end
                end
                temp_p=[];
                for j=1:length(app.PN.Completa.P)
                    if app.Posti_Macc(j).Value==app.Macchinari(i)
                        temp_p=[temp_p string(app.PN.Completa.P(j))];
                        if app.DaAnalizzare(i).SelectedObject.Text=="Si"
                        P_analizzare = [P_analizzare j];
                        end
                    end
                end
                temp_analizzare=app.DaAnalizzare(i).SelectedObject.Text=="Si";
                TableMacchinari(i,:)=table(app.Macchinari(i),{temp_t},{temp_p},temp_analizzare,'VariableNames',["Macchinario" "Transizioni" "Posti" "DaAnalizzare"]);
            end
            app.PN.Macchinari=TableMacchinari;

            %% AGGIORNO TRANSIZIONI
            for i =1:height(app.PN.Completa.T)
                temp_nome=string(app.Transizioni_nomi(i).Value);
                temp_maschera=app.Scelta_tipo(i).SelectedObject.Text=="Immediata";
                temp_peso=app.Transizioni_Peso(i).Value;
                temp_rate=app.Transizioni_Rate(i).Value;
                temp_server=app.transizioni_n_server(i).Value;
                app.PN.Completa.T(i,:)=table(temp_nome,temp_maschera,temp_peso,temp_rate,temp_server,'VariableNames',["Transizione" "Maschera" "Peso" "Rate" "Server"]);
            end
            %% AGGIORNO POSTI
            for i =1:length(app.PN.Completa.P)
                app.PN.Completa.M0(i) = app.Posti_M0(i).Value;
            end
            
            %% AGGIORNO RIDOTTA
            app.PN.Ridotta.T    = app.PN.Completa.T(T_analizzare,:);
            app.PN.Ridotta.P    = app.PN.Completa.P(P_analizzare);
            app.PN.Ridotta.M0   = app.PN.Completa.M0(P_analizzare);
            app.PN.Ridotta.Pre  = app.PN.Completa.Pre(P_analizzare,T_analizzare);
            app.PN.Ridotta.Post = app.PN.Completa.Post(P_analizzare,T_analizzare);
            app.PN.Ridotta.C    = app.PN.Completa.C(P_analizzare,T_analizzare);
            app.PN.Ridotta.H    = app.PN.Completa.H(P_analizzare,T_analizzare);
            
            %% AGGIORNO HOME E INDICI
            app.PN_Calcolata=true;
            app.StatoPN.ImageSource=app.img_aggiornata;
            app.Pagine.SelectedTab = app.PaginaPN;
            ScriviHome(app);
            ScriviIndici(app);
        end

        % Button pushed function: CalcolagrafoButton
        function CalcolagrafoButtonPushed(app, event)
            if isempty(app.PN) || isempty(app.PN.Ridotta)
                msgbox(["Attenzione!","Non è presente alcuna rete di petri."]);
            else
                app.CalcolagrafoButton.Enable="off";
                list=app.PN.Ridotta.M0;
                list_todo=app.PN.Ridotta.M0;
                timer=180;
                tic
                
                while size(list_todo,2)>0
                    % Se presente, prendo la prima colonna della lista di marcatura da
                    % studiare
                    M_ini=list_todo(:,1);
                    % Inizializzo il vettore delle transizioni abilitate in M_ini
                    T_abilitate = zeros(1,size(app.PN.Ridotta.Pre,2));

                    % Verifico quali sono le transizioni abilitate, ovvero che hanno
                    % sufficienti token in ingresso e nessun arco inibitore attivo
                    for i=1:size(app.PN.Ridotta.Pre,2)
                        if all((M_ini-app.PN.Ridotta.Pre(:,i))>=0) && ~any(M_ini>=app.PN.Ridotta.H(:,i) & app.PN.Ridotta.H(:,i)>0)
                            T_abilitate(i)=1;
                        end
                    end

                    % Verifico il numero di transizioni immediate abilitate
                    T_imm_abilitate = T_abilitate(app.PN.Ridotta.T.Maschera==1);
                    if sum(T_imm_abilitate)>0
                        T_abilitate(app.PN.Ridotta.T.Maschera==0)=0;
                    end

                    % Inizializzo indice
                    k=1;

                    Marcatura.Iniziale=M_ini;
                    Marcatura.Raggiungibili=table();

                    % Per ogni transizione abilitata calcolo le marcature a seguito dello
                    % scatto della transizione stessa.
                    for i=1:size(app.PN.Ridotta.Pre,2)
                        if T_abilitate(i)==1
                            M_fin = M_ini+app.PN.Ridotta.C(:,i);
                            if size(list,1)==0 || ~any(ismember(list',M_fin',"rows"))
                                list = [list M_fin];
                                list_todo = [list_todo M_fin];
                            end
                            idMarcatura=find(ismember(list',M_fin',"rows"));
                            Marcatura.Raggiungibili(k,:)=table(i,idMarcatura);
                            k=k+1;
                        end
                    end
                    if height(Marcatura.Raggiungibili)>0
                        Marcatura.Raggiungibili.Properties.VariableNames=[{'Transizione'} {'Marcatura'}];
                    end
                    Grafo_(ismember(list',M_ini',"rows"))=Marcatura;

                    if size(list_todo,2)>=2
                        list_todo=list_todo(:,2:end);
                    else
                        list_todo=[];
                    end

%                     if toc>timer
%                         timer=toc+60;
%                         selection = uiconfirm(app.GestoreAnalisiPN1UIFigure,'Il calcolo risulta oneroso dal punto di vista computazionale','Calcolo Grafo','Icon','warning','Options',{'Continua','Blocca'});
%                         if string(selection) == "Blocca"
%                             list_todo=[];
%                         end
%                     end
                end
                app.Grafo=Grafo_;
                app.CalcolagrafoButton.Enable="on";
            end
        end

        % Button pushed function: VisualizzagrafoButton
        function VisualizzagrafoButtonPushed(app, event)
            if isempty(app.Grafo)
                msgbox(["Attenzione!","Non è stato calcolato alcun grafo di raggiungibilità."]);
            else
                from=[];
                to=[];
                for i=1:length(app.Grafo)
                    if i==1
                        nodenames={array2string(app,app.Grafo(1).Iniziale)};
                    else
                        nodenames={nodenames{:}, array2string(app,app.Grafo(i).Iniziale)};
                    end
                    for j=1:height(app.Grafo(i).Raggiungibili)
                        from=[from i];
                        to=[to app.Grafo(i).Raggiungibili.Marcatura(j)];
                    end
                end
                
                G=digraph(from,to,ones(size(from)),nodenames);
                figure('Name','Grafo');
                plot(G,'Layout','layered');
            end
        end

        % Button pushed function: EsportaButton
        function EsportaButtonPushed(app, event)
            open(app.ContextMenu,app.EsportaButton.Position(1:2));
        end

        % Button pushed function: CercaBottone
        function CercaElemento(app, event)
            %Prendi l'elemento scritto sull'edit field Cerca_Elemento
            %Se l'elemento è presente all'interno di un elenco "macchinario",
            %restituisci i posti e le transizioni del macchinario
            %Se l'elemento è presente all'interno di un elemento "transizione"
            %restituisci le caratteristiche della transizione (tipo di
            %transizione, numero di server, rate e probabilità, macchinario di appartenenza)
            %Se l'elemento è presente all'interno di un elemento "posto"
            %restituisci le caratteristiche del posto (marcatura iniziale e
            %macchinario di appartenenza)
            %Se l'edit_field è vuoto invia un warning
            %Se l'elemento non è presente, invia un messaggio di errore
        paroladatrovare=string(app.CercaParola.Value);
        if ~isempty(find(app.Macchinari==paroladatrovare, 1))
            f=find(app.Macchinari==paroladatrovare, 1);
               temp_t=[];
                for j=1:length(app.PN.Completa.T.Transizione)
                    if app.Transizioni_Macc(j).Value==paroladatrovare
                        temp_t=[temp_t; string(['  - ', char(app.PN.Completa.T.Transizione(j))])];
                        
                    end
                end
                temp_p=[];
                for j=1:length(app.PN.Completa.P)
                    if app.Posti_Macc(j).Value==paroladatrovare
                        temp_p=[temp_p; string(['  - ', char(app.PN.Completa.P(j))])];
                        
                    end
                end
            titolo= sprintf("Macchinario trovato: %s", paroladatrovare);
            msbox_cerca_macchinario=uiconfirm(app.GestoreAnalisiPN1UIFigure, ["Il macchinario selezionato presenta le seguenti transizioni e i seguenti posti:"; ""; "Transizioni:"; temp_t; ""; "Posti:"; temp_p], string(titolo), "Icon","success");
            %crea una uimessage per mostrare le caratteristiche della
            %transizione
        elseif ~isempty(find(app.PN.Completa.T.Transizione==paroladatrovare, 1))
            %crea una uimessage per mostrare le caratteristiche della
            %transizione
            f=find(app.PN.Completa.T.Transizione==paroladatrovare, 1);
            msg=["La transizione selezionata presenta le seguenti caratteristiche:"; ""];
            msg=[msg; string(['Tipo: ', app.Scelta_tipo(f).SelectedObject.Text])];
            msg=[msg; sprintf("Peso: %i", app.Transizioni_Peso(f).Value)];
            
            if app.Scelta_tipo(f).SelectedObject.Text=="Temporizzata"
                msg=[msg; sprintf("Rate: %f", app.Transizioni_Rate(f).Value)];
                msg=[msg; sprintf("Numero server: %i", app.transizioni_n_server(f).Value)];
            end
            msg=[msg; string(['Macchinario di appartenenza: ', app.Transizioni_Macc(f).Value])];
            titolo= sprintf("Transizione trovata: %s", paroladatrovare);
            msbox_cerca_transizione=uiconfirm(app.GestoreAnalisiPN1UIFigure, msg, string(titolo), "Icon","success");
           
        elseif ~isempty(find(app.PN.Completa.P==paroladatrovare, 1))
            %crea un uimessage per mostrare le caratteristiche del posto
            f=find(app.PN.Completa.P==paroladatrovare, 1);
            msg=["Il posto selezionato presenta le seguenti caratteristiche:"; ""];
            msg=[msg; sprintf("Marcatura iniziale: %i", app.Posti_M0(f).Value)];
            msg=[msg; string(['Macchinario di appartenenza: ', app.Posti_Macc(f).Value])];
            titolo= sprintf("Posto trovato: %s", paroladatrovare);
            msbox_cerca_posto=uiconfirm(app.GestoreAnalisiPN1UIFigure, msg, string(titolo), "Icon","success" );
        elseif isempty(app.CercaParola.Value)
            %crea un uimessage con messaggio: nessuna parola inserita
            uialert(app.GestoreAnalisiPN1UIFigure, "Attenzione, nessuna parola inserita nella ricerca","Elemento mancante", "Icon","error");
        else 
            %crea un uimessage con messaggio: nessuna parola trovata
            uialert(app.GestoreAnalisiPN1UIFigure, "Nessun macchinario, transizione o posto trovato", "Elemento non trovato","Icon","warning");

        end
        end

        % Menu selected function: EsportaPNMenu
        function EsportaPNMenuSelected2(app, event)
            if isempty(app.PN) || isempty(app.PN.Completa)
                % Se non esiste la PN avviso l'utente e non salvo
                uialert(app.GestoreAnalisiPN1UIFigure, "Attenzione, non è presente alcuna rete di Petri","PN assente", "Icon","error");
                dasalvare=false;
            elseif ~app.PN_Calcolata
                % Se PN esiste ma non è aggiornata faccio scegliere
                % all'utente se aggiornarla
                selection = uiconfirm(app.GestoreAnalisiPN1UIFigure,'Vuoi esportare la rete di petri senza aver calcolato le modifiche?','Esporta','Icon','warning','Options',{'Continua','Calcola e continua','Annulla'});
                if string(selection) == "Calcola e continua"
                    CALCOLAPNButtonPushed(app,event);
                    dasalvare=true;
                elseif string(selection) == "Continua"
                    dasalvare=true;
                end
            else
                % Se PN esiste ed è aggiornata la salvo
                dasalvare=true;
            end
            if dasalvare
                [nome,path]=uiputfile('.mat','File Selection',"PN.mat");
                if ischar(nome)
                    PN=app.PN;
                    save([path,nome],"PN");
                end
            end
        end

        % Menu selected function: EsportaGrafoMenu
        function EsportaGrafoMenuSelected(app, event)
            if isempty(app.Grafo)
                uialert(app.GestoreAnalisiPN1UIFigure, "Attenzione, non è stato calcolato alcun grafo di raggiungibilità","Elemento mancante", "Icon","error");
            else
                [nome,path]=uiputfile('.mat','File Selection',"Grafo.mat");
                if ischar(nome)
                    Grafo=app.Grafo;
                    save([path,nome],"Grafo");
                end
            end
        end

        % Cell edit callback: Tabella_WIP
        function Tabella_WIPCellEdit(app, event)
            tab_WIP=app.Tabella_WIP.Data;
            tab_WIP.Properties.VariableNames=["Posto" "DaConsiderare"];
            app.PN.ImpostazioniIndici.Tabella_WIP=tab_WIP;
        end

        % Cell edit callback: Tabella_EFF
        function Tabella_EFFCellEdit(app, event)
            tab_eff=table();
            tab_eff.Macchinario=app.Tabella_EFF.Data.Macchinario;
            tab_eff.Transizione=string(app.Tabella_EFF.Data.Transizione);
            tab_eff=tab_eff(tab_eff.Transizione ~= ".",:);
            app.PN.ImpostazioniIndici.Tabella_EFF=tab_eff;
        end

        % Value changed function: TPU_sistema
        function TPU_sistemaValueChanged(app, event)
            app.PN.ImpostazioniIndici.T_Per_TPU=app.TPU_sistema.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GestoreAnalisiPN1UIFigure = uifigure('Visible', 'off');
            app.GestoreAnalisiPN1UIFigure.Name = 'GestoreAnalisiPN1';
            app.GestoreAnalisiPN1UIFigure.Icon = fullfile(pathToMLAPP, 'Icone', 'GPN.png');
            app.GrigliaEsterna = uigridlayout(app.GestoreAnalisiPN1UIFigure);
            app.ImportaDatiButton = uibutton(app.GrigliaEsterna, 'push');
            app.ImportaDatiButton.ButtonPushedFcn = createCallbackFcn(app, @ImportaDatiMenuSelected, true);
            app.ImportaDatiButton.Icon = fullfile(pathToMLAPP, 'Icone', 'Importa.png');
            app.ImportaDatiButton.Text = 'Importa Dati';
            app.CalcolagrafoButton = uibutton(app.GrigliaEsterna, 'push');
            app.CalcolagrafoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcolagrafoButtonPushed, true);
            app.CalcolagrafoButton.Icon = fullfile(pathToMLAPP, 'Icone', 'CalcolaGrafo.png');
            app.CalcolagrafoButton.Text = 'Calcola grafo';
            app.VisualizzagrafoButton = uibutton(app.GrigliaEsterna, 'push');
            app.VisualizzagrafoButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizzagrafoButtonPushed, true);
            app.VisualizzagrafoButton.Icon = fullfile(pathToMLAPP, 'Icone', 'VisualizzaGrafo.png');
            app.VisualizzagrafoButton.Text = 'Visualizza grafo';
            app.EsportaButton = uibutton(app.GrigliaEsterna, 'push');
            app.EsportaButton.ButtonPushedFcn = createCallbackFcn(app, @EsportaButtonPushed, true);
            app.EsportaButton.Icon = fullfile(pathToMLAPP, 'Icone', 'EsportaPN.png');
            app.EsportaButton.Text = 'Esporta';
            app.StatoPN = uiimage(app.GrigliaEsterna);
            app.StatoPN.Visible = 'off';
            app.StatoPN.ImageSource = fullfile(pathToMLAPP, 'Icone', 'Aggiornata.png');
            app.CercaParola = uieditfield(app.GrigliaEsterna, 'text');
            app.CercaBottone = uibutton(app.GrigliaEsterna, 'push');
            app.CercaBottone.ButtonPushedFcn = createCallbackFcn(app, @CercaElemento, true);
            app.CercaBottone.Icon = fullfile(pathToMLAPP, 'Icone', 'Ricerca.png');
            app.CercaBottone.Text = 'Cerca';
            app.Pagine = uitabgroup(app.GrigliaEsterna);
            app.PaginaPN = uitab(app.Pagine);
            app.PaginaPN.Title = 'PN';
            app.GridLayout10 = uigridlayout(app.PaginaPN);
            app.PNCOMPLETALabel = uilabel(app.GridLayout10);
            app.PNCOMPLETALabel.Text = 'PN COMPLETA';
            app.PNRIDOTTALabel = uilabel(app.GridLayout10);
            app.PNRIDOTTALabel.Text = 'PN RIDOTTA';
            app.GridCOMPLETA = uigridlayout(app.GridLayout10);
            app.TableTransizioni_C = uitable(app.GridCOMPLETA);
            app.TableTransizioni_C.ColumnName = {'TRANSIZIONI'};
            app.TableTransizioni_C.RowName = {};
            app.TablePosti_C = uitable(app.GridCOMPLETA);
            app.TablePosti_C.ColumnName = {'POSTI'; 'M0'};
            app.TablePosti_C.RowName = {};
            app.TablePosti_C.ColumnEditable = [false false];
            app.TableC_C = uitable(app.GridCOMPLETA);
            app.TableC_C.ColumnName = '';
            app.TableC_C.RowName = {};
            app.MATRICECLabel_C = uilabel(app.GridCOMPLETA);
            app.MATRICECLabel_C.Text = 'MATRICE C';
            app.MATRICEPRELabel_C = uilabel(app.GridCOMPLETA);
            app.MATRICEPRELabel_C.Text = 'MATRICE PRE';
            app.MATRICEPOSTLabel_C = uilabel(app.GridCOMPLETA);
            app.MATRICEPOSTLabel_C.Text = 'MATRICE POST';
            app.MATRICEHLabel = uilabel(app.GridCOMPLETA);
            app.MATRICEHLabel.Text = 'MATRICE H';
            app.TablePRE_C = uitable(app.GridCOMPLETA);
            app.TablePRE_C.ColumnName = '';
            app.TablePRE_C.RowName = {};
            app.TablePOST_C = uitable(app.GridCOMPLETA);
            app.TablePOST_C.ColumnName = '';
            app.TablePOST_C.RowName = {};
            app.TableH_C = uitable(app.GridCOMPLETA);
            app.TableH_C.ColumnName = '';
            app.TableH_C.RowName = {};
            app.GridRIDOTTA = uigridlayout(app.GridLayout10);
            app.TableTransizioni_R = uitable(app.GridRIDOTTA);
            app.TableTransizioni_R.ColumnName = {'TRANSIZIONI'};
            app.TableTransizioni_R.RowName = {};
            app.TablePosti_R = uitable(app.GridRIDOTTA);
            app.TablePosti_R.ColumnName = {'POSTI'; 'M0'};
            app.TablePosti_R.RowName = {};
            app.TablePosti_R.ColumnEditable = [false false];
            app.TableC_R = uitable(app.GridRIDOTTA);
            app.TableC_R.ColumnName = '';
            app.TableC_R.RowName = {};
            app.MATRICECLabel_R = uilabel(app.GridRIDOTTA);
            app.MATRICECLabel_R.Text = 'MATRICE C';
            app.MATRICEPRELabel_R = uilabel(app.GridRIDOTTA);
            app.MATRICEPRELabel_R.Text = 'MATRICE PRE';
            app.MATRICEPOSTLabel_R = uilabel(app.GridRIDOTTA);
            app.MATRICEPOSTLabel_R.Text = 'MATRICE POST';
            app.MATRICEHLabel_R = uilabel(app.GridRIDOTTA);
            app.MATRICEHLabel_R.Text = 'MATRICE H';
            app.TablePRE_R = uitable(app.GridRIDOTTA);
            app.TablePRE_R.ColumnName = '';
            app.TablePRE_R.RowName = {};
            app.TablePOST_R = uitable(app.GridRIDOTTA);
            app.TablePOST_R.ColumnName = '';
            app.TablePOST_R.RowName = {};
            app.TableH_R = uitable(app.GridRIDOTTA);
            app.TableH_R.ColumnName = '';
            app.TableH_R.RowName = {};
            app.PaginaMacchinari = uitab(app.Pagine);
            app.PaginaMacchinari.Title = 'Macchinari';
            app.GridLayout3 = uigridlayout(app.PaginaMacchinari);
            app.GrigliaMacchinari = uigridlayout(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.DAANALIZZARELabel = uilabel(app.GridLayout5);
            app.DAANALIZZARELabel.Text = 'DA ANALIZZARE';
            app.MACCHINARIOLabel = uilabel(app.GridLayout5);
            app.MACCHINARIOLabel.Text = 'MACCHINARIO';
            app.CALCOLAPNButton = uibutton(app.GridLayout3, 'push');
            app.CALCOLAPNButton.ButtonPushedFcn = createCallbackFcn(app, @CALCOLAPNButtonPushed, true);
            app.CALCOLAPNButton.Text = 'CALCOLA PN';
            app.PaginaTransizioni = uitab(app.Pagine);
            app.PaginaTransizioni.Title = 'Transizioni';
            app.GridLayout6 = uigridlayout(app.PaginaTransizioni);
            app.GrigliaTransizioni = uigridlayout(app.GridLayout6);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.TRANSIZIONELabel = uilabel(app.GridLayout7);
            app.TRANSIZIONELabel.Text = 'TRANSIZIONE';
            app.TIPOLabel = uilabel(app.GridLayout7);
            app.TIPOLabel.Text = 'TIPO';
            app.PESOLabel = uilabel(app.GridLayout7);
            app.PESOLabel.Text = 'PESO';
            app.RATELabel = uilabel(app.GridLayout7);
            app.RATELabel.Text = 'RATE';
            app.MACCHINARIOLabel_2 = uilabel(app.GridLayout7);
            app.MACCHINARIOLabel_2.Text = 'MACCHINARIO';
            app.SERVERLabel = uilabel(app.GridLayout7);
            app.SERVERLabel.Text = 'SERVER';
            app.CALCOLAPNButton_2 = uibutton(app.GridLayout6, 'push');
            app.CALCOLAPNButton_2.ButtonPushedFcn = createCallbackFcn(app, @CALCOLAPNButtonPushed, true);
            app.CALCOLAPNButton_2.Text = 'CALCOLA PN';
            app.PaginaPosti = uitab(app.Pagine);
            app.PaginaPosti.Title = 'Posti';
            app.GridLayout8 = uigridlayout(app.PaginaPosti);
            app.GrigliaPosti = uigridlayout(app.GridLayout8);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.POSTOLabel = uilabel(app.GridLayout9);
            app.POSTOLabel.Text = 'POSTO';
            app.MACCHINARIOLabel_3 = uilabel(app.GridLayout9);
            app.MACCHINARIOLabel_3.Text = 'MACCHINARIO';
            app.M0Label = uilabel(app.GridLayout9);
            app.M0Label.Text = 'M0';
            app.CALCOLAPNButton_3 = uibutton(app.GridLayout8, 'push');
            app.CALCOLAPNButton_3.ButtonPushedFcn = createCallbackFcn(app, @CALCOLAPNButtonPushed, true);
            app.CALCOLAPNButton_3.Text = 'CALCOLA PN';
            app.Indici_prestazione = uitab(app.Pagine);
            app.Indici_prestazione.Title = 'Indici di prestazione';
            app.GridLayout11 = uigridlayout(app.Indici_prestazione);
            app.Tabella_WIP = uitable(app.GridLayout11);
            app.Tabella_WIP.ColumnName = {'Posto'; 'Considerare per WIP'};
            app.Tabella_WIP.RowName = {};
            app.Tabella_WIP.ColumnEditable = [false true];
            app.Tabella_WIP.CellEditCallback = createCallbackFcn(app, @Tabella_WIPCellEdit, true);
            app.TPU_sistema = uidropdown(app.GridLayout11);
            app.TPU_sistema.Items = {};
            app.TPU_sistema.ValueChangedFcn = createCallbackFcn(app, @TPU_sistemaValueChanged, true);
            app.TPU_sistema.Value = {};
            app.UsareperthroughputsistemaLabel = uilabel(app.GridLayout11);
            app.UsareperthroughputsistemaLabel.Text = 'Usare per throughput sistema';
            app.Tabella_EFF = uitable(app.GridLayout11);
            app.Tabella_EFF.ColumnName = {'Macchinario'; 'Transizione per Efficenza'};
            app.Tabella_EFF.RowName = {};
            app.Tabella_EFF.ColumnEditable = [false true];
            app.Tabella_EFF.CellEditCallback = createCallbackFcn(app, @Tabella_EFFCellEdit, true);
            app.ContextMenu = uicontextmenu(app.GestoreAnalisiPN1UIFigure);
            app.EsportaPNMenu = uimenu(app.ContextMenu);
            app.EsportaPNMenu.MenuSelectedFcn = createCallbackFcn(app, @EsportaPNMenuSelected2, true);
            app.EsportaPNMenu.Text = 'Esporta PN';
            app.EsportaGrafoMenu = uimenu(app.ContextMenu);
            app.EsportaGrafoMenu.MenuSelectedFcn = createCallbackFcn(app, @EsportaGrafoMenuSelected, true);
            app.EsportaGrafoMenu.Text = 'Esporta Grafo';
            % Show the figure after all components are created
            app.GestoreAnalisiPN1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GestoreAnalisiPN1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GestoreAnalisiPN1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GestoreAnalisiPN1UIFigure)
        end
    end
end